classdef create_dataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button              matlab.ui.control.Button
        BeginProcessButton  matlab.ui.control.Button
        PgmConvertButton    matlab.ui.control.Button
        initialprocessled   matlab.ui.control.Lamp
        pgmprocessled       matlab.ui.control.Lamp
        TrainModelButton    matlab.ui.control.Button
        Lamp                matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginProcessButton
        function BeginProcessButtonPushed(app, event)
            global selected_directory;
            global Subdirectory;
            global Number_of_subfolders_in_subdirectories;
            selected_directory=uigetdir();
            Subdirectory = dir(fullfile(selected_directory,'*'));
            detector=vision.CascadeObjectDetector();
            totalI=0;
            Number_of_subfolders_in_subdirectories = setdiff({Subdirectory([Subdirectory.isdir]).name},{'.','..'});
            for ii = 1:numel(Number_of_subfolders_in_subdirectories)
                Imagepaths = dir(fullfile(selected_directory,Number_of_subfolders_in_subdirectories{ii},'*.jpg')); 
                C = {Imagepaths(~[Imagepaths.isdir]).name};
                disp(C);
                for jj = 1:numel(C)
                    F = fullfile(selected_directory,Number_of_subfolders_in_subdirectories{ii},C{jj});
                    [ext,name,ext] = fileparts(F);
                    try
                    imageread=imread(F);
                    totalI=totalI+1;
                    s=imresize(imageread,0.4);
                    s=rgb2gray(s);
                    bbox=step(detector,s);
                    imagecropped=imcrop(s,bbox);
                    imagecropped=imresize(imagecropped,[100,100]);
                    actual_D=F(1:52);
                    cd(actual_D);
                    imwrite(imagecropped,strcat(name,'.png'));
                    catch
                        continue;
                    end
                end
            end
            app.initialprocessled.Color=[0,1,0];
        end

        % Button pushed function: PgmConvertButton
        function PgmConvertButtonPushed(app, event)
           global selected_directory;
           global Subdirectory;
           global Number_of_subfolders_in_subdirectories; 
           for ii = 1:numel(Number_of_subfolders_in_subdirectories)
                Imagepaths = dir(fullfile(selected_directory,Number_of_subfolders_in_subdirectories{ii},'*.png')); 
                C = {Imagepaths(~[Imagepaths.isdir]).name};
                disp(C);
                for jj = 1:numel(C)
                    F = fullfile(selected_directory,Number_of_subfolders_in_subdirectories{ii},C{jj});
                    [ext,name,ext] = fileparts(F);
                    actual_D=F(1:52);
                    cd(actual_D);
                    imagecropped=imread(F);
                    imwrite(imagecropped,strcat(name,'.pgm'));
                end
            end
            app.pgmprocessled.Color=[0,1,0];
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            persistent loaded;
            persistent numeric_images;
            if(isempty(loaded))
                %all_Images=zeros(10000,6);
                for i=1:6
                    disp(strcat('E:\Internship\Teach Matlab\face_recognition\faces\',num2str(i-1)));
                    cd(strcat('E:\Internship\Teach Matlab\face_recognition\faces\',num2str(i-1)));
                    %disp(strcat('E:\Internship\Teach Matlab\face_recognition\faces',num2str(i-1)));
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'Neral-Network.jpg';
            app.Button.Text = '';
            app.BeginProcessButton = uibutton(app.UIFigure, 'push');
            app.BeginProcessButton.ButtonPushedFcn = createCallbackFcn(app, @BeginProcessButtonPushed, true);
            app.BeginProcessButton.Text = 'Begin Process';
            app.PgmConvertButton = uibutton(app.UIFigure, 'push');
            app.PgmConvertButton.ButtonPushedFcn = createCallbackFcn(app, @PgmConvertButtonPushed, true);
            app.PgmConvertButton.Text = 'Pgm Convert';
            app.initialprocessled = uilamp(app.UIFigure);
            app.pgmprocessled = uilamp(app.UIFigure);
            app.TrainModelButton = uibutton(app.UIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = create_dataset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end